classdef LeadfieldValidate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LeadfieldValidationSystem  matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        OpenleadfieldMenu          matlab.ui.container.Menu
        OpenfolderMenu             matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        ToolsMenu                  matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        ListofLeadFieldPanel       matlab.ui.container.Panel
        UITable                    matlab.ui.control.Table
        CheckBox                   matlab.ui.control.CheckBox
        AddButton                  matlab.ui.control.Button
        RemoveButton               matlab.ui.control.Button
        ValidateButton             matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function: ExitMenu, LeadfieldValidationSystem
        function LeadfieldValidationSystemCloseRequest(app, event)
            delete(app)
        end

        % Menu selected function: OpenleadfieldMenu
        function OpenleadfieldMenuSelected(app, event)
            
            [anatomical_name,anatomical_path]=uigetfile('*.mat','Select the Anatomical File');
            anatomical_file = strcat(anatomical_path,anatomical_name);
            
            [leadfield_name,leadfield_path]=uigetfile('*.mat','Select the Lead Field File');
            leadfield_file = strcat(leadfield_path,leadfield_name);
            
            newRow = {false anatomical_file leadfield_file 'show'};
            
            oldData = get(app.UITable,'Data');
            newData = [oldData; newRow];
            
            set(app.UITable,'Data',newData);
            
            %              set(app.UITable.Data{1,2},'ForegroundColor',[1 0 0])
            %app.UITable = table(['ffdgf','dsffddf','fsdfdfsff']);
            
            %             dat_archivo=strcat(ruta,archivo);
            %             disp(dat_archivo);
            %             leadfield = importdata(dat_archivo, 't', 1);
            %             matrixRange = MatrixRange(leadfield);
            %             range = matrixRange.range;
            %             text_message = strcat('The matrix range is: ',sprintf('%.0f',range) );
            %             text_message = strcat(text_message,'. Operaction complete Succefuly!');
            %             uiwait(msgbox(text_message,'Success','modal'));
            
            set(app.CheckBox,'Visible',true);
            
        end

        % Menu selected function: OpenfolderMenu
        function OpenfolderMenuSelected(app, event)
            % if the user did't select any file, then the script will search in the current folder all .mat files
            folder = uigetdir;
            
            ruta = strcat(folder,'\');
            
            ext='.mat'; % extension, si no se desea filtrar por extension poner ext=''
            ar=ls(folder);
            
            for j=1:size(ar,1)
                cn=ar(j,:);
                [~,~,ex]=fileparts(cn);
                
                %isdir(cn)
                if (and(~isfolder(fullfile(path,cn)),or(strcmpi(strtrim(ex),ext),isempty(ext))))
                    
                    %disp(fullfile(path,cn))
                    
                    dat_archivo = strcat(ruta,cn);
                    disp(dat_archivo);
                    %leadfield = importdata(dat_archivo, 't', 1);
                    
                    newRow = {false,cn,ruta,'show'};
                    
                    oldData = get(app.UITable,'Data');
                    newData = [oldData; newRow];
                    set(app.UITable,'Data',newData)
                    
                    %%%%%    validate the leadfield   %%%%%%%%%%%
                    
                    %calculate the matrix range
                    
                    % matrixRange =  MatrixRange();
                    % range = matrixRange.CalculateRange();
                    %  disp(range);
                    %%%% aca iria el programa principal
                    %%%% fid = fopen(fullfile(path,cn));
                    %%%% etc
                    
                    set(app.CheckBox,'Visible',true);
                end
                
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
            str ={'select file','select folder'};
            s = listdlg('PromptString','Select a file:','SelectionMode','single','ListString',str);
            if s==1
                OpenleadfieldMenuSelected(app, event);
            else
                OpenfolderMenuSelected(app, event);
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            oldData = get(app.UITable,'Data');
            toDelete = [];
            count = 1;
            for i = 1 : size(oldData,1)
                if(oldData{i,1} == 1)
                    toDelete(count)= i;
                    count = count +1;
                end
            end
            oldData(toDelete,:)=[];
            set(app.UITable,'Data',oldData);
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            
            rowsData = get(app.UITable,'Data');
            for i = 1 : size(rowsData,1)
                if(rowsData{i,1} == 1)
                    %                     disp(rowData{i,2});
                    %                     disp(rowData{i,3});
                    %Validate the structure file
                    result = anatomical_structure(rowsData{i,2});
                    if(result{1} ~= true  )
                        rowsData(i,2) = strcat(...
                            '<html><span style="color: #FF0000; font-weight: bold;">', ...
                            rowsData(i,2), ...
                            '</span></html>');
                    end
                    %validate the lead field file
                    result =leadfield_structure(rowsData{i,3});
                    if(result{size(result)} == 'Failed'  )
                        rowsData(i,3) = strcat(...
                            '<html><span style="color: #FF0000; font-weight: bold;">', ...
                            rowsData(i,3), ...
                            '</span></html>');
                    end
                    
                end
            end
            
            set(app.UITable,'Data',rowsData);
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            oldData = get(app.UITable,'Data');
            for i = 1 : size(oldData,1)
                if (value)
                    oldData{i,1} =true;
                else
                    oldData{i,1} = false;
                end
            end
            set(app.UITable,'Data',oldData);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            col = event.Indices(2);
            row = event.Indices(1);
            %oldData = get(app.UITable,'Data');
            
            if (col == 4)
                fig = figure();
                fig.Name = 'Lead field representation';
                assignin('base','fig',fig);
            elseif(col == 2)
                
                [anatomical_name,anatomical_path]=uigetfile('*.mat','Select the Anatomical File');
                anatomical_file = strcat(anatomical_path,anatomical_name);
                if(anatomical_name ~= 0)
                    app.UITable.Data{row,col} = anatomical_file;
                end
            elseif(col == 3)
                [leadfield_name,leadfield_path]=uigetfile('*.mat','Select the Lead Field File');
                leadfield_file = strcat(leadfield_path,leadfield_name);
                if(leadfield_file ~= 0)
                    app.UITable.Data{row,col} = leadfield_file;
                end
            else
                
            end
            
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            
            X =["Mercury","Gemini","Apollo";
                "Apollo","Gemini","Apolcvlo";
                "Mercury","Apollo","ddfbbvcb";
                "Mercury","Gemini","Apvcbvbollo";
                "Skylab","Skylab B","ISS"];
            
            %# convert matrix of numbers to cell array of strings (right aligned)
            XX = reshape(strtrim(cellstr(X(:))), size(X));
            
            %# find cells matching condition
            %             idx = ( X(:,1) > X(:,2) );
            
            %# use HTML to style these cells
            XX(2,1) = strcat(...
                '<html><span style="color: #FF0000; font-weight: bold;">', ...
                XX(2,1), ...
                '</span></html>');
            
            %# create table
            f = figure;
            h = uitable('Parent',f, 'Units','normalized', 'Position',[0.05 0.05 0.9 0.9]);
            
            %# set table data
            set(h, 'Data',XX)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LeadfieldValidationSystem = uifigure;
            app.LeadfieldValidationSystem.Name = 'LeadField Validation System';
            app.LeadfieldValidationSystem.CloseRequestFcn = createCallbackFcn(app, @LeadfieldValidationSystemCloseRequest, true);
            app.FileMenu = uimenu(app.LeadfieldValidationSystem);
            app.FileMenu.Text = 'File';
            app.OpenleadfieldMenu = uimenu(app.FileMenu);
            app.OpenleadfieldMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenleadfieldMenuSelected, true);
            app.OpenleadfieldMenu.Text = 'Open leadfield';
            app.OpenfolderMenu = uimenu(app.FileMenu);
            app.OpenfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenfolderMenuSelected, true);
            app.OpenfolderMenu.Text = 'Open folder';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @LeadfieldValidationSystemCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.LeadfieldValidationSystem);
            app.ToolsMenu.Text = 'Tools';
            app.HelpMenu = uimenu(app.LeadfieldValidationSystem);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.ListofLeadFieldPanel = uipanel(app.LeadfieldValidationSystem);
            app.ListofLeadFieldPanel.Title = 'List of Lead Field';
            app.UITable = uitable(app.ListofLeadFieldPanel);
            app.UITable.ColumnName = {''; 'Anatomical File'; 'Lead Field File'; 'Action'};
            app.UITable.ColumnEditable = [true false false false];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CheckBox = uicheckbox(app.ListofLeadFieldPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Visible = 'off';
            app.CheckBox.Text = '';
            app.AddButton = uibutton(app.ListofLeadFieldPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Tooltip = {'Add Lead Field'};
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.ListofLeadFieldPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.ValidateButton = uibutton(app.ListofLeadFieldPanel, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LeadfieldValidate

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LeadfieldValidationSystem)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LeadfieldValidationSystem)
        end
    end
end